classdef IIRFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IIRFilterUIFigure              matlab.ui.Figure
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        EnableIndividualChannelsPanel  matlab.ui.container.Panel
        HeaderSingleChannelUITable     matlab.ui.control.Table
        Switch                         matlab.ui.control.RockerSwitch
        SwitchLabel                    matlab.ui.control.Label
        FillTablewithValuesfromAbovePanel  matlab.ui.container.Panel
        FillSelectedRowsButton         matlab.ui.control.Button
        FillAllRowsButton              matlab.ui.control.Button
        SingleChannelUITable           matlab.ui.control.Table
        NotchPanel                     matlab.ui.container.Panel
        NotchButtonGroup               matlab.ui.container.ButtonGroup
        HerzLabel                      matlab.ui.control.Label
        Hz60                           matlab.ui.control.ToggleButton
        Hz50                           matlab.ui.control.ToggleButton
        NotchEnabledCheckBox           matlab.ui.control.CheckBox
        HighCutoffPanel                matlab.ui.container.Panel
        HCFrequencyHzEditField         matlab.ui.control.NumericEditField
        FrequencyHzEditField_2Label    matlab.ui.control.Label
        HCSlopedbOctButtonGroup        matlab.ui.container.ButtonGroup
        HC48                           matlab.ui.control.ToggleButton
        HC24                           matlab.ui.control.ToggleButton
        HC12                           matlab.ui.control.ToggleButton
        HCEnabledCheckBox              matlab.ui.control.CheckBox
        LowCutoffPanel                 matlab.ui.container.Panel
        LCSlopedbOctButtonGroup        matlab.ui.container.ButtonGroup
        LC48                           matlab.ui.control.ToggleButton
        LC24                           matlab.ui.control.ToggleButton
        LC12                           matlab.ui.control.ToggleButton
        TimeConstantsEditField         matlab.ui.control.NumericEditField
        TimeConstantsLabel             matlab.ui.control.Label
        LCFrequencyHzEditField         matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        LCEnabledCheckBox              matlab.ui.control.CheckBox
        PreviewUIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SampleRate
        NumberOfChannels
        ChannelLabels
        
        IIRData
        TableSelection
        
        SlopeCats = {'12';'24';'48'};
        FreqCats = {'50';'60'};
    end
    methods (Access = public)
        
        function SetValues(app, in)
        end
        
        function results = GetValues(app)
            % Get the selected Parameters
            try
                results.TableData = app.IIRData.TableData;
                results.HCenabled = app.HCEnabledCheckBox.Value;
                results.LCenabled = app.LCEnabledCheckBox.Value;
                results.Notchenabled = app.HCEnabledCheckBox.Value;
                
                results.HCF       = app.HCFrequencyHzEditField.Value;
                results.LCF       = app.LCFrequencyHzEditField.Value;
                results.NotchF    = str2double(app.NotchButtonGroup.SelectedObject.Text(1:2));
                
                results.HCslope   = str2double(app.HCSlopedbOctButtonGroup.SelectedObject.Text);
                results.LCslope   = str2double(app.LCSlopedbOctButtonGroup.SelectedObject.Text);
                results.useTable  = app.Switch.Value;
            catch e
                error(e.message);
            end
        end
        
        function fig = GetMainFigure(app)
            % The figure TransTools.CheckOptions uiwait's on, and (see
            % createComponents) the figure whose WindowStyle keeps this
            % dialog on top of its parent -- both natively, with no
            % mlapptools/undocumented-internals dependency (mlapptools was
            % removed: it existed only for this "stay on top" behaviour,
            % which uifigure's own WindowStyle already provides directly).
            fig = app.IIRFilterUIFigure;
        end
        
        function results = UpDatePlot(app)
            % To (visually) combine the effects of the chosen filters:
            % perform a fourier on the filtered impuls.
            impuls = zeros(app.SampleRate,1);
            impuls(round(app.SampleRate/2)) = 1;
            response = impuls;
            if (app.HCEnabledCheckBox.Value)
                [b,a] = TransTools.CreateFilter('High',app.SampleRate,...
                    app.HCFrequencyHzEditField.Value,...
                    str2double(app.HCSlopedbOctButtonGroup.SelectedObject.Text));
                response = filtfilt(b,a,response);
            end
            if (app.LCEnabledCheckBox.Value)
                [b,a] = TransTools.CreateFilter('Low',app.SampleRate,...
                    app.LCFrequencyHzEditField.Value,...
                    str2double(app.LCSlopedbOctButtonGroup.SelectedObject.Text));
                response = filtfilt(b,a,response);
            end
            if (app.NotchEnabledCheckBox.Value)
                [b,a] = TransTools.CreateFilter('Stop',app.SampleRate,...
                    str2double(app.NotchButtonGroup.SelectedObject.Text),...
                    24);
                response = filtfilt(b,a,response);
            end
            response = abs(fft(response));
            plot(app.PreviewUIAxes,100*response(1:round(app.SampleRate/2)));
            results = response;
        end
    end
    
    methods (Access = private)
        
        function FillTable(app, all)
            
            rowSelection = 1:app.NumberOfChannels;
            HCSlopeValues = cell(app.NumberOfChannels,1);
            LCSlopeValues = cell(app.NumberOfChannels,1);
            
            LCFreqValues = zeros(app.NumberOfChannels,1);
            HCFreqValues = zeros(app.NumberOfChannels,1);
            FreqValues = cell(app.NumberOfChannels,1);
            
            onesie = ones(app.NumberOfChannels,1) * .159155;
            
            if (~all)
                rowSelection  = app.TableSelection(:,1)';
                HCSlopeValues = cellstr(app.IIRData.TableData.HCSlopeValues);
                LCSlopeValues = cellstr(app.IIRData.TableData.LCSlopeValues);
                
                LCFreqValues = app.IIRData.TableData.LCFreqValues;
                HCFreqValues = app.IIRData.TableData.HCFreqValues;
                FreqValues   = cellstr(app.IIRData.TableData.FreqValues);
                LCSelected   = app.IIRData.TableData.LCSelected;
                HCSelected   = app.IIRData.TableData.HCSelected;
                NotchSelected= app.IIRData.TableData.NotchSelected;
            end
            
            for i = rowSelection
                HCSlopeValues{i} =  app.HCSlopedbOctButtonGroup.SelectedObject.Text;
                HCFreqValues(i) = app.HCFrequencyHzEditField.Value;
                HCSelected(i) = app.HCEnabledCheckBox.Value;
                
                LCSlopeValues{i} =  app.LCSlopedbOctButtonGroup.SelectedObject.Text;
                LCFreqValues(i) = app.LCFrequencyHzEditField.Value;
                LCSelected(i) = app.LCEnabledCheckBox.Value;
                NotchSelected(i) = app.NotchEnabledCheckBox.Value;
                FreqValues{i}  = app.NotchButtonGroup.SelectedObject.Text;
            end
            
            HCSlopeValues = categorical(HCSlopeValues, app.SlopeCats);
            LCSlopeValues = categorical(LCSlopeValues, app.SlopeCats);
            FreqValues = categorical(FreqValues, app.FreqCats);
            TCValue = onesie./LCFreqValues;
            
            if diff(size(LCSelected)) > 0
                LCSelected = LCSelected';
            end
            if diff(size(HCSelected)) > 0
                HCSelected = HCSelected';
            end
            if diff(size(NotchSelected)) > 0
                NotchSelected = NotchSelected';
            end
            if diff(size(app.ChannelLabels)>0)
                tableLabels = app.ChannelLabels;
            else
                tableLabels = app.ChannelLabels';
            end
            
            app.IIRData.TableData = table(tableLabels, ...
                LCSelected, LCFreqValues, TCValue, LCSlopeValues, ...
                HCSelected, HCFreqValues, HCSlopeValues, ...
                NotchSelected, FreqValues );
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
        % Parse varargin: Single Parameter: EEGLAB datastruct of the parent
        % Because we now know the SampleRate and the Channels we can construct the gui now
        warning('off','MATLAB:subscripting:noSubscriptsSpecified')
        app.SingleChannelUITable.Enable = 'off';
        app.FillAllRowsButton.Enable = 'off';
        app.FillSelectedRowsButton.Enable = 'off';
        if isempty(varargin)
            uiconfirm(app.IIRFilterUIFigure,'Warning: IIRFilterGui called with no data','Warning');
            return
        end
        try
            app.SampleRate         = varargin{1}.srate;
            app.ChannelLabels      = {varargin{1}.chanlocs.labels};
            app.PreviewUIAxes.XLim = [0 round(app.SampleRate/2)];
            app.PreviewUIAxes.YLim = [0 100];
            
            app.NumberOfChannels = length(app.ChannelLabels);
            app.FillTable(true);
            
            set(app.SingleChannelUITable, 'Data', app.IIRData.TableData);
            app.UpDatePlot();
        catch e
            error(e.message)
        end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
        uiconfirm(app.IIRFilterUIFigure,'Close document?','Confirm Close');
        uiresume(app.IIRFilterUIFigure);
        end

        % Value changed function: Switch
        function IndividualChannelsEnableChanged(app, event)
        value = app.Switch.Value;
        if strcmp(value, 'On')
            app.SingleChannelUITable.Enable = 'on';
            app.FillAllRowsButton.Enable = 'on';
            %app.FillSelectedRowsButton.Enable = 'on';
            app.EnableIndividualChannelsPanel.BackgroundColor = [.8,.94,.8];
        else
            app.EnableIndividualChannelsPanel.BackgroundColor = [.94,.94,.94];
            app.SingleChannelUITable.Enable = 'off';
            app.FillAllRowsButton.Enable = 'off';
            app.FillSelectedRowsButton.Enable = 'off';
        end
        end

        % Button pushed function: FillAllRowsButton
        function FillAllRows(app, event)
        app.FillTable(true);
        set(app.SingleChannelUITable, 'Data', app.IIRData.TableData);
        end

        % Value changed function: LCFrequencyHzEditField
        function LCFreqChanged(app, event)
        value = app.LCFrequencyHzEditField.Value;
        app.TimeConstantsEditField.Value = .159155/value;
        app.UpDatePlot();
        end

        % Value changed function: TimeConstantsEditField
        function TimeConstantChanged(app, event)
        value = app.TimeConstantsEditField.Value;
        app.LCFrequencyHzEditField.Value = .159155/value;
        app.UpDatePlot();
        
        end

        % Cell selection callback: SingleChannelUITable
        function SingleChannelUITableCellSelection(app, event)
        app.TableSelection = event.Indices;
        if isempty(app.TableSelection)
            app.FillSelectedRowsButton.Enable = 'off';
        else
            app.FillSelectedRowsButton.Enable = 'on';
        end
        end

        % Button pushed function: FillSelectedRowsButton
        function FillSelectedRows(app, event)
        app.FillTable(false);
        %set(app.SingleChannelUITable, 'Data', []);
        set(app.SingleChannelUITable, 'Data', app.IIRData.TableData);
        
        end

        % Button pushed function: OKButton
        function OKButtonPressed(app, event)
        app.UpDatePlot();
        uiresume(app.IIRFilterUIFigure);
        end

        % Value changed function: HCFrequencyHzEditField
        function HCFreqValueChanged(app, event)
        app.UpDatePlot();
        end

        % Value changed function: LCEnabledCheckBox
        function LCEnabledChanged(app, event)
        app.UpDatePlot();
        end

        % Value changed function: HCEnabledCheckBox
        function HCEnabledChanged(app, event)
        app.UpDatePlot();
        end

        % Value changed function: NotchEnabledCheckBox
        function NotchEnabledChanged(app, event)
        app.UpDatePlot();
        end

        % Selection changed function: LCSlopedbOctButtonGroup
        function LCSlopeChanged(app, event)
        app.UpDatePlot();
        end

        % Selection changed function: HCSlopedbOctButtonGroup
        function HCSlopeChanged(app, event)
        app.UpDatePlot();
        end

        % Selection changed function: NotchButtonGroup
        function NotchFreqChanged(app, event)
        app.UpDatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IIRFilterUIFigure = uifigure('Visible', 'off');
            app.IIRFilterUIFigure.Name = 'IIRFilter';
            % Keep this settings dialog on top of its parent (Alakazam's
            % own window) -- native uifigure property, replacing the old
            % mlapptools-based GetGuiWinToMakeModal/setAlwaysOnTop dance.
            app.PreviewUIAxes = uiaxes(app.IIRFilterUIFigure);
            xlabel(app.PreviewUIAxes, 'Hz')
            ylabel(app.PreviewUIAxes, '%')
            app.LowCutoffPanel = uipanel(app.IIRFilterUIFigure);
            app.LowCutoffPanel.Title = 'Low Cutoff';
            app.LCEnabledCheckBox = uicheckbox(app.LowCutoffPanel);
            app.LCEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @LCEnabledChanged, true);
            app.LCEnabledCheckBox.Text = 'Enabled';
            app.FrequencyHzEditFieldLabel = uilabel(app.LowCutoffPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.LCFrequencyHzEditField = uieditfield(app.LowCutoffPanel, 'numeric');
            app.LCFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @LCFreqChanged, true);
            app.LCFrequencyHzEditField.Value = 0.1;
            app.TimeConstantsLabel = uilabel(app.LowCutoffPanel);
            app.TimeConstantsLabel.Text = 'Time Constant [s]';
            app.TimeConstantsEditField = uieditfield(app.LowCutoffPanel, 'numeric');
            app.TimeConstantsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeConstantChanged, true);
            app.TimeConstantsEditField.Value = 1.591549;
            app.LCSlopedbOctButtonGroup = uibuttongroup(app.LowCutoffPanel);
            app.LCSlopedbOctButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LCSlopeChanged, true);
            app.LCSlopedbOctButtonGroup.Title = 'Slope [db/Oct]';
            app.LC12 = uitogglebutton(app.LCSlopedbOctButtonGroup);
            app.LC12.Text = '12';
            app.LC12.Value = true;
            app.LC24 = uitogglebutton(app.LCSlopedbOctButtonGroup);
            app.LC24.Text = '24';
            app.LC48 = uitogglebutton(app.LCSlopedbOctButtonGroup);
            app.LC48.Text = '48';
            app.HighCutoffPanel = uipanel(app.IIRFilterUIFigure);
            app.HighCutoffPanel.Title = 'High Cutoff';
            app.HCEnabledCheckBox = uicheckbox(app.HighCutoffPanel);
            app.HCEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @HCEnabledChanged, true);
            app.HCEnabledCheckBox.Text = 'Enabled';
            app.HCSlopedbOctButtonGroup = uibuttongroup(app.HighCutoffPanel);
            app.HCSlopedbOctButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HCSlopeChanged, true);
            app.HCSlopedbOctButtonGroup.Title = 'Slope [db/Oct]';
            app.HC12 = uitogglebutton(app.HCSlopedbOctButtonGroup);
            app.HC12.Text = '12';
            app.HC12.Value = true;
            app.HC24 = uitogglebutton(app.HCSlopedbOctButtonGroup);
            app.HC24.Text = '24';
            app.HC48 = uitogglebutton(app.HCSlopedbOctButtonGroup);
            app.HC48.Text = '48';
            app.FrequencyHzEditField_2Label = uilabel(app.HighCutoffPanel);
            app.FrequencyHzEditField_2Label.Text = 'Frequency [Hz]';
            app.HCFrequencyHzEditField = uieditfield(app.HighCutoffPanel, 'numeric');
            app.HCFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @HCFreqValueChanged, true);
            app.HCFrequencyHzEditField.Value = 30;
            app.NotchPanel = uipanel(app.IIRFilterUIFigure);
            app.NotchPanel.Title = 'Notch';
            app.NotchEnabledCheckBox = uicheckbox(app.NotchPanel);
            app.NotchEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchEnabledChanged, true);
            app.NotchEnabledCheckBox.Text = 'Enabled';
            app.NotchButtonGroup = uibuttongroup(app.NotchPanel);
            app.NotchButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NotchFreqChanged, true);
            app.Hz50 = uitogglebutton(app.NotchButtonGroup);
            app.Hz50.Text = '50';
            app.Hz50.Value = true;
            app.Hz60 = uitogglebutton(app.NotchButtonGroup);
            app.Hz60.Text = '60';
            app.HerzLabel = uilabel(app.NotchButtonGroup);
            app.HerzLabel.Text = 'Herz';
            app.EnableIndividualChannelsPanel = uipanel(app.IIRFilterUIFigure);
            app.EnableIndividualChannelsPanel.Title = 'Enable Individual Channels';
            app.SingleChannelUITable = uitable(app.EnableIndividualChannelsPanel);
            app.SingleChannelUITable.ColumnName = {'Channel'; 'Enabled'; 'Freq'; 'Time Constant'; 'slope'; 'Enabled'; 'Freq'; 'Slope'; 'Enabled'; 'Freq'};
            app.SingleChannelUITable.RowName = {};
            app.SingleChannelUITable.ColumnEditable = [false true true true true true true true true true];
            app.SingleChannelUITable.CellSelectionCallback = createCallbackFcn(app, @SingleChannelUITableCellSelection, true);
            app.FillTablewithValuesfromAbovePanel = uipanel(app.EnableIndividualChannelsPanel);
            app.FillTablewithValuesfromAbovePanel.Title = 'Fill Table with Values from Above';
            app.FillAllRowsButton = uibutton(app.FillTablewithValuesfromAbovePanel, 'push');
            app.FillAllRowsButton.ButtonPushedFcn = createCallbackFcn(app, @FillAllRows, true);
            app.FillAllRowsButton.Text = 'Fill All Rows';
            app.FillSelectedRowsButton = uibutton(app.FillTablewithValuesfromAbovePanel, 'push');
            app.FillSelectedRowsButton.ButtonPushedFcn = createCallbackFcn(app, @FillSelectedRows, true);
            app.FillSelectedRowsButton.Text = 'Fill Selected Rows';
            app.SwitchLabel = uilabel(app.EnableIndividualChannelsPanel);
            app.SwitchLabel.Text = '';
            app.Switch = uiswitch(app.EnableIndividualChannelsPanel, 'rocker');
            app.Switch.Items = {'On', 'Off'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @IndividualChannelsEnableChanged, true);
            app.HeaderSingleChannelUITable = uitable(app.EnableIndividualChannelsPanel);
            app.HeaderSingleChannelUITable.ColumnName = {''; 'Low Cutoff Filter'; 'High Cutoff Filter'; 'Notch Filter'};
            app.HeaderSingleChannelUITable.RowName = {};
            app.CancelButton = uibutton(app.IIRFilterUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.IIRFilterUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPressed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.IIRFilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IIRFilterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IIRFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IIRFilterUIFigure)
        end
    end
end